classdef PCA9698 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        I2CClose2Button  matlab.ui.control.Button
        I2CClose1Button  matlab.ui.control.Button
        I2Ctest2Button   matlab.ui.control.Button
        I2CInit2Button   matlab.ui.control.Button
        I2CInit1Button   matlab.ui.control.Button
        I2COpen2Button   matlab.ui.control.Button
        I2COpen1Button   matlab.ui.control.Button
        btI2CInfo        matlab.ui.control.Button
        btMpsse          matlab.ui.control.Button
        btLoadLibrary    matlab.ui.control.Button
        I2Ctest1Button   matlab.ui.control.Button
    end

    properties(Constant)

        I2C_TRANSFER_OPTIONS_START_BIT = 0x00000001;
        I2C_TRANSFER_OPTIONS_STOP_BIT = 0x00000002;
        I2C_TRANSFER_OPTIONS_BREAK_ON_NACK = 0x00000004;
        I2C_TRANSFER_OPTIONS_NACK_LAST_BYTE = 0x00000008;
        I2C_TRANSFER_OPTIONS_FAST_TRANSFER = 0x00000030;
        I2C_TRANSFER_OPTIONS_FAST_TRANSFER_BYTES = 0x00000010;
        I2C_TRANSFER_OPTIONS_FAST_TRANSFER_BITS = 0x00000020;
        I2C_TRANSFER_OPTIONS_NO_ADDRESS = 0x00000040;

    end

    properties (Access = private)
        LN = 'libmpsse'; % library name
    end

    properties (Access = private)
        ftH (1,2) cell
        ftHOpenFlag (1,2) logical
        ftHInitFlag (1,2) logical
    end    
    
    methods (Access = private)
        
        function initLib(app)
            calllib(app.LN, 'Init_libMPSSE');
        end

        function cleanupLib(app)
            app.I2CClose1ButtonPushed();
            app.I2CClose2ButtonPushed();
            calllib(app.LN, 'Cleanup_libMPSSE');
        end        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~libisloaded(app.LN)
                loadlibrary('libmpsse.dll',@libmpsse);
                app.initLib();
            end       
            app.ftH{1} = libpointer('voidPtr', 1);
            app.ftH{2} = libpointer('voidPtr', 1);
            app.ftHOpenFlag = [false, false];
            app.ftHInitFlag = [false, false];
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: btLoadLibrary
        function btLoadLibraryPushed(app, event)
            if libisloaded(app.LN)
                app.cleanupLib();
                unloadlibrary(app.LN);
            end
            if ~libisloaded(app.LN)
                loadlibrary('libmpsse.dll',@libmpsse);
                app.initLib();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if libisloaded(app.LN)
                    app.cleanupLib();
                    unloadlibrary(app.LN);
                end
            catch 
            end
            delete(app);
        end

        % Button pushed function: btMpsse
        function btMpsseButtonPushed(app, event)
            fprintf("\nVersion Check\n");
            verMPSSE=libpointer('uint32Ptr',1);
            verD2XX=libpointer('uint32Ptr',1);
            status=calllib(app.LN,'Ver_libMPSSE',verMPSSE, verD2XX);
            if status, disp(status); end
            fprintf("libmpsse: %08x\n", verMPSSE.Value);
            fprintf("libftd2xx: %08x\n", verD2XX.Value);
        end

        % Button pushed function: btI2CInfo
        function btI2CInfoButtonPushed(app, event)

            fprintf("\nTest case 1 - I2C_GetNumChannels\n");
            channels=libpointer('uint32Ptr',1);
            status=calllib(app.LN,'I2C_GetNumChannels',channels);
            fprintf("        I2C_GetNumChannels returned %d; channels=%d\n", status, channels.Value);

            FT_DEVICE_LIST_INFO_NODE = struct(...
                'Flags', uint32(0), ...
                'Type', uint32(0), ...
                'ID', uint32(0), ...
                'LocId', uint32(0), ...
                'SerialNumber', zeros(1, 16, 'int8'), ...
                'Description', zeros(1, 64, 'int8'), ...
                'ftHandle', libpointer('voidPtr', 1));

            fprintf("\nTest case 2 - I2C_GetChannelInfo\n");
            chanInfo = libstruct('s_ft_device_list_info_node', FT_DEVICE_LIST_INFO_NODE);
            for idx=1:channels.Value
                status=calllib(app.LN,'I2C_GetChannelInfo',idx-1, chanInfo);
                t = get(chanInfo);
                fprintf("        I2C_GetChannelInfo returned %d for channel =%d\n", status, idx-1);
    
                fprintf("        Flags=0x%x\n", t.Flags);
                fprintf("        Type=0x%x\n", t.Type);
                fprintf("        ID=0x%x\n", t.ID);
                fprintf("        LocId=0x%x\n", t.LocId);
                fprintf("        SerialNumber=%s\n", native2unicode(t.SerialNumber));
                fprintf("        Description=%s\n", native2unicode(t.Description));
                % fprintf("        ftHandle=%d (should be zero)\n", t.ftHandle.Value);
            end

        end

        % Button pushed function: I2COpen1Button
        function I2COpen1ButtonPushed(app, event)
            if ~app.ftHOpenFlag(1)
                status=calllib(app.LN,'I2C_OpenChannel',0, app.ftH{1});
                if status == 0
                    disp('Channel[0] Open successfully.');
                    app.ftHOpenFlag(1) = true; 
                end
            end
        end

        % Button pushed function: I2COpen2Button
        function I2COpen2ButtonPushed(app, event)
            if ~app.ftHOpenFlag(2)
                status=calllib(app.LN,'I2C_OpenChannel',1, app.ftH{2});
                if status == 0 
                    disp('Channel[1] Open successfully.');
                    app.ftHOpenFlag(2) = true; 
                end
            end            
        end

        % Button pushed function: I2CClose1Button
        function I2CClose1ButtonPushed(app, event)
            if app.ftHOpenFlag(1)
                status=calllib(app.LN,'I2C_CloseChannel',app.ftH{1});
                 if status == 0
                     disp('Channel[0] Close successfully.');
                 end
                app.ftHOpenFlag(1) = false;
                app.ftHInitFlag(1) = false;
            end
        end

        % Button pushed function: I2CClose2Button
        function I2CClose2ButtonPushed(app, event)
            if app.ftHOpenFlag(2)
                status=calllib(app.LN,'I2C_CloseChannel',app.ftH{2});
                if status == 0
                     disp('Channel[1] Close successfully.');
                 end                
                app.ftHOpenFlag(2) = false;
                app.ftHInitFlag(2) = false;
            end
        end

        % Button pushed function: I2CInit1Button
        function I2CInit1ButtonPushed(app, event)
            if ~app.ftHOpenFlag(1), return; end

            i2cOpt = bitor(app.I2C_TRANSFER_OPTIONS_START_BIT, app.I2C_TRANSFER_OPTIONS_STOP_BIT, 'uint32');
            % i2cOpt = bitor(i2cOpt, app.I2C_TRANSFER_OPTIONS_FAST_TRANSFER_BITS, 'uint32');

            % Define the I2C_ChannelConfig structure
            s_I2C_ChannelConfig = struct(...
                'ClockRate', uint32(100000), ... % Default clock rate (e.g., I2C_CLOCK_STANDARD_MODE)
                'LatencyTimer', uint8(16), ...  % Default latency timer (e.g., 16ms)
                'Options', uint32(i2cOpt), ...       % Default options (e.g., 0 for no options enabled)
                'Pin', uint32(0), ...           % Default pin configuration
                'currentPinState', uint16(0)); % Default current pin state
    
            % Convert the structure to a libstruct
            ChannelConfig = libstruct('ChannelConfig_i2c', s_I2C_ChannelConfig);

            status = calllib(app.LN, 'I2C_InitChannel', app.ftH{1}, ChannelConfig);
            if status == 0
                app.ftHInitFlag(1) = true;
                disp('Channel[0] initialized successfully.');
            end
        end

        % Button pushed function: I2CInit2Button
        function I2CInit2ButtonPushed(app, event)
            if ~app.ftHOpenFlag(2), return; end

            i2cOpt = bitor(app.I2C_TRANSFER_OPTIONS_START_BIT, app.I2C_TRANSFER_OPTIONS_STOP_BIT, 'uint32');
            % i2cOpt = bitor(i2cOpt, app.I2C_TRANSFER_OPTIONS_FAST_TRANSFER_BITS, 'uint32');

            % Define the I2C_ChannelConfig structure
            s_I2C_ChannelConfig = struct(...
                'ClockRate', uint32(100000), ... % Default clock rate (e.g., I2C_CLOCK_STANDARD_MODE)
                'LatencyTimer', uint8(16), ...  % Default latency timer (e.g., 16ms)
                'Options', uint32(i2cOpt), ...       % Default options (e.g., 0 for no options enabled)
                'Pin', uint32(0), ...           % Default pin configuration
                'currentPinState', uint16(0)); % Default current pin state
    
            % Convert the structure to a libstruct
            ChannelConfig = libstruct('ChannelConfig_i2c', s_I2C_ChannelConfig);
            
            status = calllib(app.LN, 'I2C_InitChannel', app.ftH{2}, ChannelConfig);
            if status == 0
                app.ftHInitFlag(2) = true;
                disp('Channel[1] initialized successfully.');
            end            
        end

        % Button pushed function: I2Ctest1Button
        function I2Ctest1ButtonPushed(app, event)
            if app.ftHOpenFlag(1) == false || app.ftHInitFlag(1) == false, return; end
            try
                testPCA9698(app.LN, app.ftH{1} );
            catch e
                disp(e.message);
            end

        end

        % Button pushed function: I2Ctest2Button
        function I2Ctest2ButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.I2Ctest1Button = uibutton(app.GridLayout, 'push');
            app.I2Ctest1Button.ButtonPushedFcn = createCallbackFcn(app, @I2Ctest1ButtonPushed, true);
            app.I2Ctest1Button.Text = 'I2C test (1)';
            app.btLoadLibrary = uibutton(app.GridLayout, 'push');
            app.btLoadLibrary.ButtonPushedFcn = createCallbackFcn(app, @btLoadLibraryPushed, true);
            app.btLoadLibrary.Text = 'Library';
            app.btMpsse = uibutton(app.GridLayout, 'push');
            app.btMpsse.ButtonPushedFcn = createCallbackFcn(app, @btMpsseButtonPushed, true);
            app.btMpsse.Text = {'Lib MPSSE '; 'Version'};
            app.btI2CInfo = uibutton(app.GridLayout, 'push');
            app.btI2CInfo.ButtonPushedFcn = createCallbackFcn(app, @btI2CInfoButtonPushed, true);
            app.btI2CInfo.Text = {'I2C Channel'; 'Information'};
            app.I2COpen1Button = uibutton(app.GridLayout, 'push');
            app.I2COpen1Button.ButtonPushedFcn = createCallbackFcn(app, @I2COpen1ButtonPushed, true);
            app.I2COpen1Button.Text = 'I2C Open (1)';
            app.I2COpen2Button = uibutton(app.GridLayout, 'push');
            app.I2COpen2Button.ButtonPushedFcn = createCallbackFcn(app, @I2COpen2ButtonPushed, true);
            app.I2COpen2Button.Text = 'I2C Open (2)';
            app.I2CInit1Button = uibutton(app.GridLayout, 'push');
            app.I2CInit1Button.ButtonPushedFcn = createCallbackFcn(app, @I2CInit1ButtonPushed, true);
            app.I2CInit1Button.Text = 'I2C Init (1)';
            app.I2CInit2Button = uibutton(app.GridLayout, 'push');
            app.I2CInit2Button.ButtonPushedFcn = createCallbackFcn(app, @I2CInit2ButtonPushed, true);
            app.I2CInit2Button.Text = 'I2C Init (2)';
            app.I2Ctest2Button = uibutton(app.GridLayout, 'push');
            app.I2Ctest2Button.ButtonPushedFcn = createCallbackFcn(app, @I2Ctest2ButtonPushed, true);
            app.I2Ctest2Button.Text = 'I2C test (2)';
            app.I2CClose1Button = uibutton(app.GridLayout, 'push');
            app.I2CClose1Button.ButtonPushedFcn = createCallbackFcn(app, @I2CClose1ButtonPushed, true);
            app.I2CClose1Button.Text = 'I2C Close (1)';
            app.I2CClose2Button = uibutton(app.GridLayout, 'push');
            app.I2CClose2Button.ButtonPushedFcn = createCallbackFcn(app, @I2CClose2ButtonPushed, true);
            app.I2CClose2Button.Text = 'I2C Close (2)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCA9698

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end